classdef NNB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        InputFileTypeDropDown           matlab.ui.control.DropDown
        InputFileTypeDropDownLabel      matlab.ui.control.Label
        MaturationEfficiencyEditField   matlab.ui.control.NumericEditField
        MaturationEfficiencyEditFieldLabel  matlab.ui.control.Label
        LoadLastSettingsButton          matlab.ui.control.Button
        DisplayStatus                   matlab.ui.control.EditField
        ProcessNNBTimelapsesLabel       matlab.ui.control.Label
        InputPathEditField              matlab.ui.control.EditField
        InputPathLabel                  matlab.ui.control.Label
        CodePathEditField               matlab.ui.control.EditField
        CodePathEditFieldLabel          matlab.ui.control.Label
        FijiPathEditField               matlab.ui.control.EditField
        FijiPathEditFieldLabel          matlab.ui.control.Label
        SelecttheinputfolderLabel       matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        QualitythresholdEditField       matlab.ui.control.NumericEditField
        QualitythresholdEditFieldLabel  matlab.ui.control.Label
        SpotRadiusEditField             matlab.ui.control.NumericEditField
        SpotRadiusEditFieldLabel        matlab.ui.control.Label
        SelectthecodefolderLabel        matlab.ui.control.Label
        SelectCodeFolder                matlab.ui.control.Button
        SelecttheFijiappfolderLabel     matlab.ui.control.Label
        SelectFijiFolderButton          matlab.ui.control.Button
        ProcessImagesButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        RootFolder %the home directory
        ValidInputs %table containing inputFieldNames and verification
    end

    methods (Access = private)

        function [] = processImages(app, fijiPath, inputFolder, spot_radius, quality_threshold, maturationEfficiency, ext, channel)
            
            %add the scripts folder to the path so matlab knows where ImageJ script is
            scriptsPath = fullfile(fijiPath, 'scripts');
            addpath(scriptsPath)

            results_folder = fullfile(inputFolder, 'Results');
            if isfolder(results_folder)
                answer = '';
                while ~strcmp(answer,'Continue')
                    answer = questdlg('Warning! a Results Folder already exists. Proceeding could overwrite previous data'...
                        , 'Existing Data', 'Continue', 'Cancel', 'Continue');
                    if strcmp(answer, 'Cancel') || strcmp(answer, '')
                        error('Execution canceled by folder check dialog box')
                    end
                end
            else
                mkdir(results_folder)
            end
            

            % Get the list of files with the correct extension in the provided folder
            files = dir(fullfile(inputFolder,ext));
            filenames = {files.name};
            filenames = sort(filenames);
            totalAcquisitions = numel(filenames);

            % Start ImageJ for image analysis
            ImageJ;
            import ij.*

            % create the array of figures so that we can loop through them at the end
            figures = gobjects(1,totalAcquisitions);

            progressBar = waitbar(0,'Starting Up');
            % Process each image
            for index = 1:length(filenames)
                currentfilename = filenames{index};
                imageName = extractBefore(currentfilename, '.nd2');
                subFolderPath = fullfile(results_folder, imageName);
                waitbar((index-1)/totalAcquisitions, progressBar, ['Processing ', imageName])
                updateStatus(app, ['Processing ', imageName]);
                mkdir(subFolderPath);

                convertToTiff(inputFolder, subFolderPath, currentfilename);

                tifFilePath = fullfile(subFolderPath, [imageName, '.tif']);

                [~, ~, OriginalStack] = readInTimelapse(tifFilePath);

                firstFramesProjection = generateFirstFramesProjection(subFolderPath, OriginalStack);

                saveTrackStatisticsCSV(firstFramesProjection, subFolderPath, spot_radius, quality_threshold, str2double(channel))

                prepareFolderForFigureCreation(subFolderPath, OriginalStack)

                % instantiate the figure and make it invisible so that MATLAB doesn't steal
                % focus when editing the figures
                
                figures(index) = figure('Visible', 'off');
                
                % actually add the traces to the figure
                createInteractiveFigure(subFolderPath, figures(index), maturationEfficiency);
                % set(figures(index), 'CreateFcn', 'set(gcbo,''Visible'',''on'')set(gcbo, ''CreateFcn'', '''')');
                savefig(figures(index), fullfile(subFolderPath, 'interactiveFig'))
                % close(figures(index))
            end

            waitbar(1, progressBar, 'Saving Figures')
            % make all the firgures visible for future use
            for i = 1:numel(filenames)
                currentfilename = filenames{i};
                imageName = extractBefore(currentfilename, '.nd2');
                subFolderPath = fullfile(results_folder, imageName);
                set(figures(i), 'visible', 'on');
                figFilePath = fullfile(subFolderPath, 'interactiveFig');
                savefig(figures(i), figFilePath)
                close(figures(i))
            end

            ij.IJ.run("Quit","");
            close(progressBar)
            updateStatus(app, 'All Done!');
        end
        
        function complete = updateStatus(app, message)
            app.DisplayStatus.Value = message;
            complete = true;
        end
        
        function checkInput(app, inputValue, inputName)
            correctInput = false;
            switch inputName
                case "Fiji Path"
                    fijiPathPieces = split(inputValue, filesep);
                    indexOfLastPiece = length(fijiPathPieces);
                    fijiName = fijiPathPieces(indexOfLastPiece);
                    if strcmp(fijiName, 'Fiji.app')
                        correctInput = true;
                    end
                case "Code Folder Path"
                    codePathPieces = split(inputValue, filesep);
                    indexOfLastPiece = length(codePathPieces);
                    codeFolderName = codePathPieces(indexOfLastPiece);
                    if (strcmp(codeFolderName, 'TIRF_ProcessingCode')...
                            || strcmp(codeFolderName,'TIRF_ProcessingCode-main'))
                        correctInput = true;
                    end
                case "Input Folder Path"
                    if isfolder(inputValue)
                        correctInput = true;
                    end
                case "Spot Radius"
                    if isnumeric(inputValue)
                        correctInput = true;
                    end
                case "Quality Threshold"
                    if isnumeric(inputValue)
                        correctInput = true;
                    end
                case "Maturation Efficiency"
                    if isnumeric(inputValue)
                        correctInput = true;
                    end
            end
            if correctInput
                app.ValidInputs(inputName) = true;
            else
                app.ValidInputs(inputName) = false;
                h = msgbox(strjoin(["Provided", inputName, " '",...
                    inputValue, "' ", "was not valid"]), 'Invalid Selection!');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appName = 'NNB_GUI';

            % PATH SEARCH
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                    Flag = 0;
                end
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), appName);

                if ~isempty(prjPath) & strcmp(prjPath.Name, appName)
                    app.RootFolder = char(prjPath.RootFolder);
                elseif isfolder(appPath)
                    app.RootFolder = appPath;
                else
                    Flag = 0;
                end
            end

            % INITIALIZATION
            if Flag
                app.ValidInputs = containers.Map(["Fiji Path"; ...
                    "Code Folder Path"; "Spot Radius"; ...
                    "Quality Threshold"; "Maturation Efficiency";...
                    "Input Folder Path"],logical([0;0;0;0;0;0]));
            else
                % Error message...
            end


        end

        % Button pushed function: SelectFijiFolderButton
        function SelectFijiFolderButtonPushed(app, event)
            commandwindow();
            if ismac
                [fijiName, fijiAppPath] = uigetfile('Fiji.app', 'Select the Fiji.app', '/Applications/');
                fijiPath = fullfile(fijiAppPath, fijiName);
            elseif ispc
                fijiPath = uigetdir('C:\', 'Select the Fiji.app folder');
            end
            checkInput(app, fijiPath, "Fiji Path")
            drawnow;
            figure(app.UIFigure)
            app.FijiPathEditField.Value = fijiPath;

        end

        % Button pushed function: SelectCodeFolder
        function SelectCodeFolderPushed(app, event)
            commandwindow();
            codeFolder = uigetdir('/Documents/', 'Select the code folder');
            checkInput(app, codeFolder, "Code Folder Path")
            drawnow;
            figure(app.UIFigure)
            app.CodePathEditField.Value = codeFolder;
        end

        % Value changed function: SpotRadiusEditField
        function SpotRadiusEditFieldValueChanged(app, event)
            spot_radius = app.SpotRadiusEditField.Value;
            checkInput(app, spot_radius, "Spot Radius")
        end

        % Value changed function: QualitythresholdEditField
        function QualitythresholdEditFieldValueChanged(app, event)
            quality_threshold = app.QualitythresholdEditField.Value;
            checkInput(app, quality_threshold, "Quality Threshold")
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            commandwindow();
            inputFolder = uigetdir('/Documents/', 'Select the input folder');
            checkInput(app, inputFolder, "Input Folder Path")
            drawnow;
            figure(app.UIFigure)
            app.InputPathEditField.Value = inputFolder;
        end

        % Button pushed function: ProcessImagesButton
        function ProcessImagesButtonPushed(app, event)
            fijiPath = app.FijiPathEditField.Value;
            checkInput(app, fijiPath, "Fiji Path")
            
            codeFolder = app.CodePathEditField.Value;
            checkInput(app, codeFolder, "Code Folder Path")

            spot_radius = app.SpotRadiusEditField.Value;
            checkInput(app, spot_radius, "Spot Radius")

            quality_threshold = app.QualitythresholdEditField.Value;
            checkInput(app, quality_threshold, "Quality Threshold")

            maturationEfficiency = app.MaturationEfficiencyEditField.Value;
            checkInput(app, maturationEfficiency, "Maturation Efficiency")
            
            inputFolder = app.InputPathEditField.Value;
            checkInput(app, inputFolder, "Input Folder Path")

            extension = app.InputFileTypeDropDown.Value;
            channel = app.ChannelDropDown.Value;
            
            if ~all(cellfun(@(x) isequal(x, true), values(app.ValidInputs)))
                error('One or more inputs invalid')
            else
                appPath = mfilename('fullpath');
                appPathPieces = split(appPath, filesep);
                indexOfLastAppPathPiece = length(appPathPieces);
                lengthOfLast = length(cell2mat(appPathPieces(indexOfLastAppPathPiece)));
                appFolderPath = appPath(1:length(appPath)-lengthOfLast-1);
                disp(['appPath: ', appFolderPath, '; Root Folder: ', app.RootFolder])
                settingsPath = fullfile(appFolderPath,'SettingsNNB_GUI.mat');
                save(settingsPath,"fijiPath","spot_radius",...
                    "quality_threshold", "codeFolder", "inputFolder",...
                    "maturationEfficiency", "extension", "channel")
                answer = '';
                while ~strcmp(answer,'Yes')
                    answer = questdlg(['Does input folder contain only the .nd2 files you' ...
                        ' wish to process? i.e. no 3 channel images, partial timelapses,' ...
                        ' or other files/folders'], 'Folder Check', 'Yes',...
                        'No (Select another folder)', 'Cancel', 'Yes');
                    if strcmp(answer, 'Cancel') || strcmp(answer, '')
                        error('Execution canceled by folder check dialog box')
                    end
                end
                updateStatus(app, 'Processing Started');
                processImages(app, fijiPath, inputFolder, spot_radius,...
                    quality_threshold, maturationEfficiency, extension, channel)
            end
        end

        % Button pushed function: LoadLastSettingsButton
        function LoadLastSettingsButtonPushed(app, event)
            appPath = mfilename('fullpath');
            appPathPieces = split(appPath, filesep);
            indexOfLastAppPathPiece = length(appPathPieces);
            lengthOfLast = length(cell2mat(appPathPieces(indexOfLastAppPathPiece)));
            appFolderPath = appPath(1:length(appPath)-lengthOfLast-1);
            settingsPath = fullfile(appFolderPath,'SettingsNNB_GUI.mat');
            load(settingsPath, 'fijiPath', 'spot_radius', 'quality_threshold',...
                'codeFolder', 'inputFolder', 'maturationEfficiency', 'extension', 'channel')
            app.FijiPathEditField.Value = fijiPath;
            app.SpotRadiusEditField.Value = spot_radius;
            app.QualitythresholdEditField.Value = quality_threshold;
            app.CodePathEditField.Value = codeFolder;
            app.InputPathEditField.Value = inputFolder;
            app.MaturationEfficiencyEditField.Value = maturationEfficiency;
            app.InputFileTypeDropDown.Value = extension;
            app.ChannelDropDown.Value = channel;

        end

        % Value changed function: MaturationEfficiencyEditField
        function MaturationEfficiencyEditFieldValueChanged(app, event)
            maturation_efficiency = app.MaturationEfficiencyEditField.Value;
            checkInput(app, maturation_efficiency, "Maturation Efficiency")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessImagesButton = uibutton(app.UIFigure, 'push');
            app.ProcessImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImagesButtonPushed, true);
            app.ProcessImagesButton.Text = 'Process Images';
            app.SelectFijiFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFijiFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFijiFolderButtonPushed, true);
            app.SelectFijiFolderButton.Text = 'Select File';
            app.SelecttheFijiappfolderLabel = uilabel(app.UIFigure);
            app.SelecttheFijiappfolderLabel.Text = 'Select the Fiji.app folder';
            app.SelectCodeFolder = uibutton(app.UIFigure, 'push');
            app.SelectCodeFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectCodeFolderPushed, true);
            app.SelectCodeFolder.Text = 'Select File';
            app.SelectthecodefolderLabel = uilabel(app.UIFigure);
            app.SelectthecodefolderLabel.Text = 'Select the code folder';
            app.SpotRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.SpotRadiusEditFieldLabel.Text = 'Spot Radius';
            app.SpotRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpotRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SpotRadiusEditFieldValueChanged, true);
            app.SpotRadiusEditField.Value = 3;
            app.QualitythresholdEditFieldLabel = uilabel(app.UIFigure);
            app.QualitythresholdEditFieldLabel.Text = 'Quality threshold';
            app.QualitythresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.QualitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @QualitythresholdEditFieldValueChanged, true);
            app.QualitythresholdEditField.Value = 50;
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelecttheinputfolderLabel = uilabel(app.UIFigure);
            app.SelecttheinputfolderLabel.Text = 'Select the input folder';
            app.FijiPathEditFieldLabel = uilabel(app.UIFigure);
            app.FijiPathEditFieldLabel.Text = 'Fiji Path';
            app.FijiPathEditField = uieditfield(app.UIFigure, 'text');
            app.CodePathEditFieldLabel = uilabel(app.UIFigure);
            app.CodePathEditFieldLabel.Text = 'Code Path';
            app.CodePathEditField = uieditfield(app.UIFigure, 'text');
            app.InputPathLabel = uilabel(app.UIFigure);
            app.InputPathLabel.Text = 'Input Path';
            app.InputPathEditField = uieditfield(app.UIFigure, 'text');
            app.ProcessNNBTimelapsesLabel = uilabel(app.UIFigure);
            app.ProcessNNBTimelapsesLabel.Text = 'Process NNB Timelapses';
            app.DisplayStatus = uieditfield(app.UIFigure, 'text');
            app.LoadLastSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadLastSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastSettingsButtonPushed, true);
            app.LoadLastSettingsButton.Text = 'Load Last Settings';
            app.MaturationEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.MaturationEfficiencyEditFieldLabel.Text = 'Maturation Efficiency';
            app.MaturationEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaturationEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @MaturationEfficiencyEditFieldValueChanged, true);
            app.MaturationEfficiencyEditField.Value = 0.7;
            app.InputFileTypeDropDownLabel = uilabel(app.UIFigure);
            app.InputFileTypeDropDownLabel.Text = 'Input File Type';
            app.InputFileTypeDropDown = uidropdown(app.UIFigure);
            app.InputFileTypeDropDown.Items = {'*.nd2', '*.tif'};
            app.InputFileTypeDropDown.Value = '*.nd2';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'1', '2', '3'};
            app.ChannelDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NNB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end